classdef FEMLabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMLabUIFigure              matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SavesettingsMenu            matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        ProblemSetupTab             matlab.ui.container.Tab
        ProblemSetupPanel           matlab.ui.container.Panel
        RegionsPanel                matlab.ui.container.Panel
        IDTable                     matlab.ui.control.Table
        NameTable                   matlab.ui.control.Table
        TypeTable                   matlab.ui.control.Table
        MatPropTable                matlab.ui.control.Table
        SourPropTable               matlab.ui.control.Table
        ProblemtypeButtonGroup      matlab.ui.container.ButtonGroup
        ElectrostaticButton         matlab.ui.control.RadioButton
        StaticcurrentButton         matlab.ui.control.RadioButton
        MagnetostaticButton         matlab.ui.control.RadioButton
        SymmetryButtonGroup         matlab.ui.container.ButtonGroup
        PlanesymmetricButton        matlab.ui.control.RadioButton
        AxissymmetricButton         matlab.ui.control.RadioButton
        CleanprojectButton          matlab.ui.control.Button
        EditParamsButton            matlab.ui.control.Button
        GeofileLabel                matlab.ui.control.Label
        GeofileEditField            matlab.ui.control.EditField
        MeshfileEditFieldLabel      matlab.ui.control.Label
        MeshfileEditField           matlab.ui.control.EditField
        UpdateParamsButton          matlab.ui.control.Button
        StateLampLabel              matlab.ui.control.Label
        problemSetupStateLamp       matlab.ui.control.Lamp
        MesherTab                   matlab.ui.container.Tab
        CurrentMeshPanel            matlab.ui.container.Panel
        meshDisplayAxes             matlab.ui.control.UIAxes
        MesherPanel                 matlab.ui.container.Panel
        ElementtypeButtonGroup      matlab.ui.container.ButtonGroup
        Triangle1stOrderButton      matlab.ui.control.RadioButton
        Triangle2ndOrderButton      matlab.ui.control.RadioButton
        Triangle3rdOrderButton      matlab.ui.control.RadioButton
        ElemTypeAxes                matlab.ui.control.UIAxes
        RunsetupButton              matlab.ui.control.Button
        EditgeometryButton          matlab.ui.control.Button
        RefinemeshButton            matlab.ui.control.Button
        MeshInfoTable               matlab.ui.control.Table
        MeshgeometryButton          matlab.ui.control.Button
        StateLampLabel_2            matlab.ui.control.Label
        mesherStateLamp             matlab.ui.control.Lamp
        SolverTab                   matlab.ui.container.Tab
        PreprocPanel                matlab.ui.container.Panel
        CalculationtypeButtonGroup  matlab.ui.container.ButtonGroup
        IntegrationButton           matlab.ui.control.RadioButton
        ExactformulaButton          matlab.ui.control.RadioButton
        ProcessButton               matlab.ui.control.Button
        StatePanel                  matlab.ui.container.Panel
        PreprocessingLampLabel      matlab.ui.control.Label
        PreprocessingLamp           matlab.ui.control.Lamp
        EdgetypeButtonGroup         matlab.ui.container.ButtonGroup
        RectangularButton           matlab.ui.control.RadioButton
        CircularButton              matlab.ui.control.RadioButton
        ComputationPanel            matlab.ui.container.Panel
        FieldPanel                  matlab.ui.container.Panel
        ComputeEButton              matlab.ui.control.Button
        ComputeJButton              matlab.ui.control.Button
        ComputeBButton              matlab.ui.control.Button
        ComputeAButton              matlab.ui.control.Button
        EcomputedLabel              matlab.ui.control.Label
        EcomputedLamp               matlab.ui.control.Lamp
        JcomputedLampLabel          matlab.ui.control.Label
        JcomputedLamp               matlab.ui.control.Lamp
        BcomputedLampLabel          matlab.ui.control.Label
        BcomputedLamp               matlab.ui.control.Lamp
        AcomputedLampLabel          matlab.ui.control.Label
        AcomputedLamp               matlab.ui.control.Lamp
        IntegralsPanel              matlab.ui.container.Panel
        StateLampLabel_3            matlab.ui.control.Label
        solverStateLamp             matlab.ui.control.Lamp
        PlotTab                     matlab.ui.container.Tab
        PostprocPanel               matlab.ui.container.Panel
        FieldTButtonGroup           matlab.ui.container.ButtonGroup
        EButton                     matlab.ui.control.RadioButton
        JButton                     matlab.ui.control.RadioButton
        BButton                     matlab.ui.control.RadioButton
        AButton                     matlab.ui.control.RadioButton
        PlottypeButtonGroup         matlab.ui.container.ButtonGroup
        AbsButton                   matlab.ui.control.RadioButton
        AbsTriButton                matlab.ui.control.RadioButton
        QuiverButton                matlab.ui.control.RadioButton
        ContourButton               matlab.ui.control.RadioButton
        SliceButton                 matlab.ui.control.RadioButton
        FieldlinesButton            matlab.ui.control.RadioButton
        OptionsPanel                matlab.ui.container.Panel
        AxesOnCheckBox              matlab.ui.control.CheckBox
        SavePlotCheckBox            matlab.ui.control.CheckBox
        FieldlinesOnCheckBox        matlab.ui.control.CheckBox
        contoursEditFieldLabel      matlab.ui.control.Label
        contoursEditField           matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        ResolutionEditField         matlab.ui.control.NumericEditField
        FormatDropDownLabel         matlab.ui.control.Label
        FormatDropDown              matlab.ui.control.DropDown
        DisplayButton               matlab.ui.control.Button
        CloseallfiguresButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        projPath = pwd; % Path to project
        projName = 'Default.proj'; % Initial project name
        
    end
    
    properties (Access = public)
        
        projectSelected = 1;
        
        files;
        
        settings = struct('problemClass', 'Electrostatic',...
            'symmetry', 'Plane symmetric',...
            'computation', 'Integration', ...
            'edgeType', 'Rectangular');
        
        plotSettings = struct('field', 'E', 'type', 'abs',...
            'axesOn', true, 'savePlot', false, ...
            'format', '-dpng', 'flinesOn', false,...
            'nCont', 24, 'res', 200, 'hideFig', ...
            false);
      
        
        meshInfo = struct('nNodes', 0, 'nElements', 0, 'nSys', 0);
        
        regSet;
        meshPreviewSet = false;
        cmd;
        
        % Tab states :      1 - START
        %                   2 - INIT
        %                   3 - SETUP_OK
        %                   0 - DOWN
        DOWN = 0;
        START = 1;
        INIT = 2;
        SETUP_OK = 3;
        
        problemSetupState = DOWN;
        mesherState = DOWN;
        solverState = DOWN;
        
        problemSetupTabElementsVisible = false;
        mesherTabElementsVisible = true;
        solverTabElementsVisible = true;
        plotTabElementsVisible = true;
        
    end
    
    methods (Access = private)
        
        % Init fields :
        function initGuiItems(app)
            app.ProblemtypeButtonGroup.SelectedObject = 1;
        end
        
        % Changes the visibility of the tools if no proj selected
        
        function setProjectState(app, state)
            switch state
                case {'INIT'}
                    
                    app.projectSelected = true;
                    
                    app.resetRegionTables()
                    app.resetMeshInfoTables()
                    app.resetCurrentMeshDisplay()
                    
                    if(app.solverTabElementsVisible)
                        app.changeSolverTabState()
                    end
                    if(app.plotTabElementsVisible)
                        app.changePlotTabState()
                    end
                    app.TabGroup.Visible = 'on';
                    
                    app.updateStates(app.START, app.START, app.START, app.START)

                case {'SOLV'}
                    if(~app.solverTabElementsVisible)
                        app.changeSolverTabState()
                    end
                case {'PLOT'}
                    if(~app.plotTabElementsVisible)
                        app.changePlotTabState()
                    end
                otherwise
                    error('Wrong state string')
            end
        end
    
        function updateStates(app, probSetState, meshState, solvState, ~)
            
            app.problemSetupState = probSetState;
            app.mesherState = meshState;
            app.solverState = solvState;
            
            app.updateProblemSetup()
            app.updateMesher()
            app.updateSolver()
            
        end
    
        function updateProblemSetup(app)
            switch app.problemSetupState
                case app.START
                    app.setProblemSetupSTART()
                case app.INIT
                    app.setProblemSetupINIT()
                case app.SETUP_OK
                    app.setProblemSetupSETUP_OK()
            end
        end
    
        function updateMesher(app)
            switch app.mesherState
                case app.START
                    app.setMesherSTART()
                case app.INIT
                    app.setMesherINIT()
                case app.SETUP_OK
                    app.setMesherSETUP_OK()
            end
        end
    
        function updateSolver(app)
            switch app.solverState
                case app.START
                    app.setSolverSTART()
                case app.INIT
                    app.setSolverINIT()
                case app.SETUP_OK
                    app.setSolverSETUP_OK()
            end
        end
    
        function setProblemSetupSTART(app)
            app.problemSetupStateLamp.Color = app.RED;
            app.resetRegionTables()
        end
    
        function setProblemSetupINIT(app)
            app.problemSetupStateLamp.Color = app.YELLOW;
            
        end
    
        function setProblemSetupSETUP_OK(app)
            app.problemSetupStateLamp.Color = app.GREEN;
        end
    
        function setMesherSTART(app)
            app.mesherStateLamp.Color = app.RED;
            app.resetMeshInfoTables()
            app.resetCurrentMeshDisplay()
        end
    
        function setMesherINIT(app)
            app.mesherStateLamp.Color = app.YELLOW;
        end
    
        function setMesherSETUP_OK(app)
            app.mesherStateLamp.Color = app.GREEN;
            % add all updates here !
        end
    
        function setSolverSTART(app)
            app.solverStateLamp.Color = app.RED;
            % update possible calculations
        end
    
        function setSolverINIT(app)
            app.solverStateLamp = app.YELLOW;
            
        end
    
        function setSolverSETUP_OK(app)
            app.solverStateLamp.Color = app.GREEN;
            
        end
    
        function resetRegionTables(app)
            app.IDTable.Data = {};
            app.NameTable.Data = {};
            app.TypeTable.Data = {};
            app.MatPropTable.Data = {};
            app.SourPropTable.Data = {};
        end
    
        function resetMeshInfoTables(app)
            app.MeshInfoTable.Data = {};
        end
    
        function resetCurrentMeshDisplay(app)
            clf(app.meshDisplayAxes)
        end
        
        function resetTabs(app)
            app.ProblemSetupTab.reset;
            app.MesherTab.reset;
            app.SolverTab.reset;
            app.PlotTab.reset;
        end
        
        function [state, isState] = getState(app, boolVar)
            isState = ~boolVar;
            if(isState)
                state = 'on';
            else
                state = 'off';
            end
        end
    
        function changeProblemSetupTabState(app)
            
            [state, isVisible] = app.getState(app.problemSetupTabElementsVisible); 
           
            app.ProblemSetupPanel.Visible = state;
            
            app.problemSetupTabElementsVisible = isVisible;
            
        end
        
        function changeMesherTabState(app)
            
            [state, isVisible] = app.getState(app.mesherTabElementsVisible); 
            
            app.MesherPanel.Visible = state;
            app.CurrentMeshPanel.Visible = state;
            
            app.mesherTabElementsVisible = isVisible;
            
        end
        
        function changeSolverTabState(app)
            
            [state, isVisible] = app.getState(app.solverTabElementsVisible);
            
            app.PreprocPanel.Visible = state;
            app.ComputationPanel.Visible = state;
            
            app.solverTabElementsVisible = isVisible;
            
        end
    
        function changePlotTabState(app)
            
            [state, isVisible] = app.getState(app.plotTabElementsVisible);
            
            app.PostprocPanel.Visible = state;
            
            app.plotTabElementsVisible = isVisible;
            
        end
        
        function state = checkButtonState(app, button)
            value = button.Text;
            switch value
                case 'Electrostatic'
                    state = app.STATIC_E;
                case 'Static current'
                    state = app.STATIC_C;
                case 'Magnetostatic'
                    state = app.STATIC_M;
                case 'Plane symmetric'
                    state = app.PLANE_SYM;
                case 'Axis symmetric'
                    state = app.AXIS_SYM;
                case 'Integration'
                    state = app.COMP_INT;
                case 'Exact formula'
                    state = app.COMP_FORM;
                case 'Rectangular'
                    state = app.RECT;
                case 'Circular'
                    state = app.CIRC;
            end
        end
        
        function updateProblemSettings(app)
            
            settings = app.settings;
            save(app.files.setfile, 'settings')
            
        end
        
        function changePreprocState(app, state)
            if(strcmp(state, 'busy'))
                app.PreprocessingLamp.Color = [1,1,0];
            elseif(strcmp(state, 'ready'))
                app.PreprocessingLamp.Color = [0,1,0];
            elseif(strcmp(state, 'init'))
                app.PreprocessingLamp.Color = [1,0,0];
            end
        end
        
        function changeMesherState(app, state)
            if(strcmp(state, 'busy'))
                app.StateLamp.Color = [1,1,0];
            elseif(strcmp(state, 'ready'))
                app.StateLamp.Color = [0,1,0];
            elseif(strcmp(state, 'init'))
                app.StateLamp.Color = [1,0,0];
            end
        end
        
        function startPreprocessor(app)
            
            app.changePreprocState('busy')
            msh.prepFieldData(app.files, app.settings)
            slv.calcA(app.files, app.settings)
            slv.evalA(app.files)
            app.changePreprocState('ready')
            app.setProjectState('PLOT')
            
        end
        
        function cleanProjectFolder(app)
            [status, message, messageid] = rmdir(app.files.pltpth, 's');
            [status, message, messageid] = rmdir(app.files.results, 's');
            delete(app.files.setfile);
            app.initComponents()
            app.updateStates(app.START, app.START, app.START, app.START)
        end
        
        function changeComputeLampState(app, lamp, label, state)
            if(strcmp(state, 'busy'))
                lamp.Color = [1,1,0];
                label.Text = 'Computing...';
            elseif(strcmp(state, 'ready'))
                lamp.Color = [0,1,0];
                label.Text = 'Computed';
            elseif(strcmp(state, 'init'))
                lamp.Color = [1,0,0];
                label.Text = 'Not computed';
            end
        end
        
        function initRegionTables(app)
            app.regSet = ap.getRegions(app.files);
            
            app.IDTable.Data = {app.regSet.id}';
            app.NameTable.Data = {app.regSet.name}';
            app.SourPropTable.Data = {app.regSet.srcProp}';
            app.MatPropTable.Data = {app.regSet.matProp}';
            app.TypeTable.Data = {app.regSet.spec}';
            
            %app.NameTable.ColumnEditable = true;
            %app.MatPropTable.ColumnEditable = true;
            %app.SourPropTable.ColumnEditable = true;
            %app.TypeTable.ColumnEditable = true;
        end
        
    end
    
    methods (Access = public)
        
        function initData(app, filePath, fileName)
            
            app.files = io.generateFiles(filePath, fileName);
            app.cmd = gmsh.generateCommands(app.files);
            
        end
        
        function initComponents(app)
            
            addpath('functions')
            
            % Project selected state :
            app.TabGroup.Visible = 'off';
            
            % ElemTypeAxes init :
            title(app.ElemTypeAxes, '')
            ylabel(app.ElemTypeAxes, '')
            xlabel(app.ElemTypeAxes, '')
            app.setElementTypeImg(1)
            
            % UIAxes :
            title(app.meshDisplayAxes, '')
            ylabel(app.meshDisplayAxes, '')
            xlabel(app.meshDisplayAxes, '')
            
            % Project name init
            app.setProjectTitle()
            
            %app.initRegionTablesTables()
            app.meshPreviewSet = false;
            
            %% Lamps intializer :
            % MeshreadyLamp
            app.StateLamp.Color = [1,0,0];
            
            % Preprocessed lamp :
            app.PreprocessingLamp.Color = [1,0,0];
            
            % Computation lamps :
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'init')
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'init')
            app.changeComputeLampState(app.JcomputedLamp, app.JcomputedLampLabel, 'init')
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'init')
            
        end
        
        % Sets the element type image
        function setElementTypeImg(app, i)
            img = imread(['./AppFiles/figures/', num2str(i), 'o_triangle.png']);
            imshow(img, 'parent', app.ElemTypeAxes)
        end
        
        % Sets project title
        function setProjectTitle(app)
            app.PreprocPanel.Title = app.projName;
            app.MesherPanel.Title = app.projName;
            app.PostprocPanel.Title = app.projName;
            app.ProblemSetupPanel.Title = app.projName;
            
            if(isempty(app.files))
                app.GeofileEditField.Value = 'default.geo';
                app.MeshfileEditField.Value = 'default.msh';
            else
                app.GeofileEditField.Value = app.files.geo;
                app.MeshfileEditField.Value = app.files.mesh;
            end
        end
        
        function setMeshPreview(app)
            gfx.display(app.files, app.settings, 'MSH', 'saveplot', true, ...
                'hideFig', true)
            img = imread([app.files.pltpthMesh, '.png']);
            imshow(img, 'parent', app.meshDisplayAxes)
        end
        
        function setPlotType(app, buttonStr)
            switch buttonStr
                case 'Abs'
                    app.plotSettings.type = 'abs';
                case 'Abs Tri'
                    app.plotSettings.type = 'abstri';
                case 'Quiver'
                    app.plotSettings.type = 'quiv';
                case 'Contour'
                    app.plotSettings.type = 'cont';
                case 'Slice'
                    app.plotSettings.type = 'slice';
                case 'Field lines'
                    app.plotSettings.type = 'flines';
            end
        end
        
        function setFormatIdn(app, value)
            switch value
                case 'PNG'
                    app.plotSettings.format = '-dpng';
                case 'EPS'
                    app.plotSettings.format = '-depsc';
                case 'JPG'
                    app.plotSettings.format = '-djpg';
            end
        end
        
        function projectSetup(app, state)
            
            if(strcmp(state, 'NEW'))
                
                prompt = 'Project name :';
                dlgtitle = 'Enter project name :';
                definput = {'Problem'};
                dims = [1 60];
                
                inputCell = inputdlg(prompt,dlgtitle,dims,definput);
                
                app.projName = inputCell{1};
                
                selectFolderString = 'Select location for project';
                
                projectPath = uigetdir(pwd, selectFolderString);
                app.projPath = [projectPath, '\', app.projName];
                
                mkdir(app.projPath)
                
            elseif(strcmp(state, 'OPEN'))
                
                selectFolderString = 'Select project folder';
                
                app.projPath = uigetdir(pwd, selectFolderString);
                pathParts = strsplit(app.projPath, '\');
                app.projName = pathParts{end};
            end
            
        end
        
        function updateMeshInfoTable(app)
            app.meshInfo = msh.updateMeshInfo(app.files, app.meshInfo);
            data = {app.meshInfo.nNodes, app.meshInfo.nElements, app.meshInfo.nSys};
            app.MeshInfoTable.Data = data;
        end
    end
    

    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            app.projectSetup('OPEN')
            app.initComponents()
            app.initData(app.projPath, app.projName)
            
            app.files = io.parseGmesh(app.files);
            io.initConfigParams(app.files);
            
            app.setProjectTitle()
            
            app.setProjectState('INIT')
            
        end

        % Selection changed function: ProblemtypeButtonGroup
        function ProblemtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemtypeButtonGroup.SelectedObject;
            app.settings.problemClass = selectedButton.Text;
            app.updateProblemSettings();
            app.updateStates(app.INIT, -1, app.START, app.START)
        end

        % Selection changed function: SymmetryButtonGroup
        function SymmetryButtonGroupSelectionChanged(app, event)
            selectedButton = app.SymmetryButtonGroup.SelectedObject;
            app.settings.symmetry = selectedButton.Text;
            app.updateProblemSettings();
            app.updateStates(app.INIT, -1, app.START, app.START)
        end

        % Selection changed function: CalculationtypeButtonGroup
        function CalculationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationtypeButtonGroup.SelectedObject;
            app.settings.computation = selectedButton.Text;
            app.updateProblemSettings();
            app.updateStates(-1, -1, app.INIT, -1)
        end

        % Selection changed function: EdgetypeButtonGroup
        function EdgetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.EdgetypeButtonGroup.SelectedObject;
            app.settings.edgeType = selectedButton.Text;
            app.updateProblemSettings();
            app.updateStates(-1, -1, app.INIT, -1)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.startPreprocessor()
            app.updateStates(-1, -1, app.SETUP_OK, -1)
        end

        % Button pushed function: CleanprojectButton
        function CleanprojectButtonPushed(app, event)
            app.cleanProjectFolder()
            app.updateStates(app.START, app.START, -1, -1)
        end

        % Button pushed function: ComputeEButton
        function ComputeEButtonPushed(app, event)
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'busy')
            slv.calcE(app.files, app.settings)
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'ready')
        end

        % Button pushed function: ComputeJButton
        function ComputeJButtonPushed(app, event)
            app.changeComputeLampState(app.JcomputedLamp, app.JcomputedLampLabel, 'busy')
            slv.calcJ(app.files, app.settings)
            app.changeComputeLampState(app.JcomputedLamp, app.EcomputedLampLabel, 'ready')
        end

        % Button pushed function: ComputeBButton
        function ComputeBButtonPushed(app, event)
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'busy')
            slv.calcB(app.files, app.settings)
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'ready')
        end

        % Button pushed function: ComputeAButton
        function ComputeAButtonPushed(app, event)
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'busy')
            slv.evalA(app.files)
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'ready')
        end

        % Selection changed function: FieldTButtonGroup
        function FieldTButtonGroupSelectionChanged(app, event)
            selectedButton = app.FieldTButtonGroup.SelectedObject;
            app.plotSettings.field = selectedButton.Text;
        end

        % Selection changed function: PlottypeButtonGroup
        function PlottypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottypeButtonGroup.SelectedObject;
            app.setPlotType(selectedButton.Text);
        end

        % Value changed function: AxesOnCheckBox
        function AxesOnCheckBoxValueChanged(app, event)
            value = app.AxesOnCheckBox.Value;
            app.plotSettings.axesOn = value;
        end

        % Value changed function: SavePlotCheckBox
        function SavePlotCheckBoxValueChanged(app, event)
            value = app.SavePlotCheckBox.Value;
            app.plotSettings.savePlot = value;
        end

        % Value changed function: FieldlinesOnCheckBox
        function FieldlinesOnCheckBoxValueChanged(app, event)
            value = app.FieldlinesOnCheckBox.Value;
            app.plotSettings.flinesOn = value;
        end

        % Value changed function: contoursEditField
        function contoursEditFieldValueChanged(app, event)
            value = app.contoursEditField.Value;
            app.plotSettings.nCont = value;
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.plotSettings.res = value;
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            value = app.FormatDropDown.Value;
            app.setFormatIdn(value);
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            gfx.display(app.files, app.settings, app.plotSettings.field, 'type', app.plotSettings.type,...
                'savePlot', app.plotSettings.savePlot, 'fieldLinesOn', app.plotSettings.flinesOn, ...
                'nCont', app.plotSettings.nCont, 'res', app.plotSettings.res, 'format', app.plotSettings.format)
        end

        % Callback function
        function RegionParamsTableCellEdit(app, event)
            app.regParams = app.RegionParamsTable.Data;
        end

        % Cell edit callback: NameTable
        function NameTableCellEdit(app, event)
            
            app.regSet = app.regSet.setRegRegs(app.NameTable.Data', app.regSet.regs.values);
            app.regSet = app.regSet.setRegDef(app.NameTable.Data', app.regSet.def.values);
            app.regSet = app.regSet.setRegMap(app.regSet.rMap.keys, app.NameTable.Data');
            
            ap.setRegions(app.files, app.regSet)
        end

        % Callback function
        function ParamTableCellEdit(app, event)
            
            app.regSet = app.regSet.setRegRegs(app.regSet.regs.keys, app.ParamTable.Data');
            ap.setRegions(app.files, app.regSet)
        end

        % Cell edit callback: TypeTable
        function TypeTableCellEdit(app, event)
            %app.updateRegParams()
        end

        % Button pushed function: EditParamsButton
        function EditParamsButtonPushed(app, event)
            open(app.files.regfile);
            app.updateStates(app.INIT, -1, app.START, app.START)
        end

        % Button pushed function: UpdateParamsButton
        function UpdateParamsButtonPushed(app, event)
            msh.regionsSetup(app.files)
            app.initRegionTables()
            app.updateStates(app.SETUP_OK, -1, -1, -1)
        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            close all;
        end

        % Button pushed function: EditgeometryButton
        function EditgeometryButtonPushed(app, event)
            [status, ~] = gmsh.run(app.cmd.editGeo);
            if(status ~= 0)
                error('Something went wrong')
            end
            app.updateStates(app.START, app.START, app.START, app.START)
        end

        % Button pushed function: MeshgeometryButton
        function MeshgeometryButtonPushed(app, event)
            [status, ~] = gmsh.run(app.cmd.meshAndSave);
            [status, ~] = gmsh.run(app.cmd.refineMesh);
            app.updateStates(-1, app.INIT, app.START, app.START)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            
            % Setup folder structure and set path
            app.projectSetup('NEW')
            
            % Initialize project
            app.initData(app.projPath, app.projName)
            app.setProjectTitle()
            
            % Make mesh and save
            gmsh.run(app.cmd.editGeo)
            gmsh.run(app.cmd.meshAndSave)
            
            app.files = io.parseGmesh(app.files);
            io.initConfigParams(app.files);
            app.setProjectState('INIT')
        end

        % Button pushed function: RefinemeshButton
        function RefinemeshButtonPushed(app, event)
            [status, ~] = gmsh.run(app.cmd.refineMesh);
        end

        % Button pushed function: RunsetupButton
        function RunsetupButtonPushed(app, event)
            app.files = io.parseGmesh(app.files);
            io.initConfigParams(app.files);
            msh.regionsSetup(app.files)
            app.updateMeshInfoTable()
            app.setMeshPreview()
            app.setProjectState('SOLV')
            app.changeMesherState('ready')
            
            app.updateStates(-1, app.SETUP_OK, -1, -1)
        end

        % Selection changed function: ElementtypeButtonGroup
        function ElementtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ElementtypeButtonGroup.SelectedObject;
            
            app.updateStates(app.START, app.START, app.START, app.START)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMLabUIFigure = uifigure;
            app.FEMLabUIFigure.Name = 'FEMLab';
            app.FileMenu = uimenu(app.FEMLabUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SavesettingsMenu = uimenu(app.FileMenu);
            app.SavesettingsMenu.Accelerator = 'S';
            app.SavesettingsMenu.Text = 'Save settings';
            app.HelpMenu = uimenu(app.FEMLabUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.FEMLabUIFigure);
            app.ProblemSetupTab = uitab(app.TabGroup);
            app.ProblemSetupTab.Title = 'Problem Setup';
            app.ProblemSetupPanel = uipanel(app.ProblemSetupTab);
            app.ProblemSetupPanel.Title = 'Default.proj';
            app.RegionsPanel = uipanel(app.ProblemSetupPanel);
            app.RegionsPanel.Title = 'Regions : ';
            app.IDTable = uitable(app.RegionsPanel);
            app.IDTable.ColumnName = {'ID'};
            app.IDTable.RowName = {};
            app.NameTable = uitable(app.RegionsPanel);
            app.NameTable.ColumnName = {'Name'};
            app.NameTable.RowName = {};
            app.NameTable.CellEditCallback = createCallbackFcn(app, @NameTableCellEdit, true);
            app.TypeTable = uitable(app.RegionsPanel);
            app.TypeTable.ColumnName = {'Type'};
            app.TypeTable.RowName = {};
            app.TypeTable.CellEditCallback = createCallbackFcn(app, @TypeTableCellEdit, true);
            app.MatPropTable = uitable(app.RegionsPanel);
            app.MatPropTable.ColumnName = {'Material Prop'};
            app.MatPropTable.RowName = {};
            app.SourPropTable = uitable(app.RegionsPanel);
            app.SourPropTable.ColumnName = {'Source Prop'};
            app.SourPropTable.RowName = {};
            app.ProblemtypeButtonGroup = uibuttongroup(app.ProblemSetupPanel);
            app.ProblemtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemtypeButtonGroupSelectionChanged, true);
            app.ProblemtypeButtonGroup.Title = 'Problem type : ';
            app.ElectrostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.ElectrostaticButton.Text = 'Electrostatic';
            app.ElectrostaticButton.Value = true;
            app.StaticcurrentButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.StaticcurrentButton.Text = 'Static current';
            app.MagnetostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.MagnetostaticButton.Text = 'Magnetostatic';
            app.SymmetryButtonGroup = uibuttongroup(app.ProblemSetupPanel);
            app.SymmetryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SymmetryButtonGroupSelectionChanged, true);
            app.SymmetryButtonGroup.Title = 'Symmetry :';
            app.PlanesymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.PlanesymmetricButton.Text = 'Plane symmetric';
            app.PlanesymmetricButton.Value = true;
            app.AxissymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.AxissymmetricButton.Text = 'Axis symmetric';
            app.CleanprojectButton = uibutton(app.ProblemSetupPanel, 'push');
            app.CleanprojectButton.ButtonPushedFcn = createCallbackFcn(app, @CleanprojectButtonPushed, true);
            app.CleanprojectButton.Text = 'Clean project';
            app.EditParamsButton = uibutton(app.ProblemSetupPanel, 'push');
            app.EditParamsButton.ButtonPushedFcn = createCallbackFcn(app, @EditParamsButtonPushed, true);
            app.EditParamsButton.Text = 'Edit params';
            app.GeofileLabel = uilabel(app.ProblemSetupPanel);
            app.GeofileLabel.Text = 'Geo file : ';
            app.GeofileEditField = uieditfield(app.ProblemSetupPanel, 'text');
            app.GeofileEditField.Value = 'default.geo';
            app.MeshfileEditFieldLabel = uilabel(app.ProblemSetupPanel);
            app.MeshfileEditFieldLabel.Text = 'Mesh file : ';
            app.MeshfileEditField = uieditfield(app.ProblemSetupPanel, 'text');
            app.MeshfileEditField.Value = 'default.msh';
            app.UpdateParamsButton = uibutton(app.ProblemSetupPanel, 'push');
            app.UpdateParamsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParamsButtonPushed, true);
            app.UpdateParamsButton.Text = 'Update params';
            app.StateLampLabel = uilabel(app.ProblemSetupPanel);
            app.StateLampLabel.Text = 'State';
            app.problemSetupStateLamp = uilamp(app.ProblemSetupPanel);
            app.MesherTab = uitab(app.TabGroup);
            app.MesherTab.Title = 'Mesher';
            app.CurrentMeshPanel = uipanel(app.MesherTab);
            app.CurrentMeshPanel.Title = 'Current Mesh ';
            app.meshDisplayAxes = uiaxes(app.CurrentMeshPanel);
            app.meshDisplayAxes.XTick = [];
            app.meshDisplayAxes.YTick = [];
            app.MesherPanel = uipanel(app.MesherTab);
            app.MesherPanel.Title = 'Default.proj';
            app.ElementtypeButtonGroup = uibuttongroup(app.MesherPanel);
            app.ElementtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ElementtypeButtonGroupSelectionChanged, true);
            app.ElementtypeButtonGroup.Title = 'Element type :';
            app.Triangle1stOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle1stOrderButton.Text = 'Triangle 1st Order';
            app.Triangle1stOrderButton.Value = true;
            app.Triangle2ndOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle2ndOrderButton.Text = 'Triangle 2nd Order';
            app.Triangle3rdOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle3rdOrderButton.Text = 'Triangle 3rd Order';
            app.ElemTypeAxes = uiaxes(app.ElementtypeButtonGroup);
            title(app.ElemTypeAxes, 'Title')
            xlabel(app.ElemTypeAxes, 'X')
            ylabel(app.ElemTypeAxes, 'Y')
            app.ElemTypeAxes.XTick = [];
            app.ElemTypeAxes.YTick = [];
            app.RunsetupButton = uibutton(app.MesherPanel, 'push');
            app.RunsetupButton.ButtonPushedFcn = createCallbackFcn(app, @RunsetupButtonPushed, true);
            app.RunsetupButton.Text = 'Run setup';
            app.EditgeometryButton = uibutton(app.MesherPanel, 'push');
            app.EditgeometryButton.ButtonPushedFcn = createCallbackFcn(app, @EditgeometryButtonPushed, true);
            app.EditgeometryButton.Text = 'Edit geometry';
            app.RefinemeshButton = uibutton(app.MesherPanel, 'push');
            app.RefinemeshButton.ButtonPushedFcn = createCallbackFcn(app, @RefinemeshButtonPushed, true);
            app.RefinemeshButton.Text = 'Refine mesh';
            app.MeshInfoTable = uitable(app.MesherPanel);
            app.MeshInfoTable.ColumnName = {'# Nodes'; '# Elements'; '# Unknowns'};
            app.MeshInfoTable.RowName = {};
            app.MeshgeometryButton = uibutton(app.MesherPanel, 'push');
            app.MeshgeometryButton.ButtonPushedFcn = createCallbackFcn(app, @MeshgeometryButtonPushed, true);
            app.MeshgeometryButton.Text = 'Mesh geometry';
            app.StateLampLabel_2 = uilabel(app.MesherPanel);
            app.StateLampLabel_2.Text = 'State';
            app.mesherStateLamp = uilamp(app.MesherPanel);
            app.SolverTab = uitab(app.TabGroup);
            app.SolverTab.Title = 'Solver';
            app.PreprocPanel = uipanel(app.SolverTab);
            app.PreprocPanel.Title = 'Default.proj';
            app.CalculationtypeButtonGroup = uibuttongroup(app.PreprocPanel);
            app.CalculationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationtypeButtonGroupSelectionChanged, true);
            app.CalculationtypeButtonGroup.Title = 'Calculation type : ';
            app.IntegrationButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.IntegrationButton.Text = 'Integration';
            app.IntegrationButton.Value = true;
            app.ExactformulaButton = uiradiobutton(app.CalculationtypeButtonGroup);
            app.ExactformulaButton.Text = 'Exact formula';
            app.ProcessButton = uibutton(app.PreprocPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.StatePanel = uipanel(app.PreprocPanel);
            app.StatePanel.Title = 'State : ';
            app.PreprocessingLampLabel = uilabel(app.StatePanel);
            app.PreprocessingLampLabel.Text = 'Preprocessing';
            app.PreprocessingLamp = uilamp(app.StatePanel);
            app.EdgetypeButtonGroup = uibuttongroup(app.PreprocPanel);
            app.EdgetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EdgetypeButtonGroupSelectionChanged, true);
            app.EdgetypeButtonGroup.Title = 'Edge type : ';
            app.RectangularButton = uiradiobutton(app.EdgetypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.CircularButton = uiradiobutton(app.EdgetypeButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.ComputationPanel = uipanel(app.PreprocPanel);
            app.ComputationPanel.Title = 'Computation';
            app.FieldPanel = uipanel(app.ComputationPanel);
            app.FieldPanel.Title = 'Field';
            app.ComputeEButton = uibutton(app.FieldPanel, 'push');
            app.ComputeEButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeEButtonPushed, true);
            app.ComputeEButton.Text = 'Compute E';
            app.ComputeJButton = uibutton(app.FieldPanel, 'push');
            app.ComputeJButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeJButtonPushed, true);
            app.ComputeJButton.Text = 'Compute J';
            app.ComputeBButton = uibutton(app.FieldPanel, 'push');
            app.ComputeBButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeBButtonPushed, true);
            app.ComputeBButton.Text = 'Compute B';
            app.ComputeAButton = uibutton(app.FieldPanel, 'push');
            app.ComputeAButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeAButtonPushed, true);
            app.ComputeAButton.Text = 'Compute A';
            app.EcomputedLabel = uilabel(app.FieldPanel);
            app.EcomputedLabel.Text = 'E computed :';
            app.EcomputedLamp = uilamp(app.FieldPanel);
            app.JcomputedLampLabel = uilabel(app.FieldPanel);
            app.JcomputedLampLabel.Text = 'J computed :';
            app.JcomputedLamp = uilamp(app.FieldPanel);
            app.BcomputedLampLabel = uilabel(app.FieldPanel);
            app.BcomputedLampLabel.Text = 'B computed :';
            app.BcomputedLamp = uilamp(app.FieldPanel);
            app.AcomputedLampLabel = uilabel(app.FieldPanel);
            app.AcomputedLampLabel.Text = 'A computed :';
            app.AcomputedLamp = uilamp(app.FieldPanel);
            app.IntegralsPanel = uipanel(app.PreprocPanel);
            app.IntegralsPanel.Title = 'Integrals';
            app.StateLampLabel_3 = uilabel(app.PreprocPanel);
            app.StateLampLabel_3.Text = 'State';
            app.solverStateLamp = uilamp(app.PreprocPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PostprocPanel = uipanel(app.PlotTab);
            app.PostprocPanel.Title = 'Default.proj';
            app.FieldTButtonGroup = uibuttongroup(app.PostprocPanel);
            app.FieldTButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FieldTButtonGroupSelectionChanged, true);
            app.FieldTButtonGroup.Title = 'Plot quantity  : ';
            app.EButton = uiradiobutton(app.FieldTButtonGroup);
            app.EButton.Text = 'E';
            app.EButton.Value = true;
            app.JButton = uiradiobutton(app.FieldTButtonGroup);
            app.JButton.Text = 'J';
            app.BButton = uiradiobutton(app.FieldTButtonGroup);
            app.BButton.Text = 'B';
            app.AButton = uiradiobutton(app.FieldTButtonGroup);
            app.AButton.Text = 'A';
            app.PlottypeButtonGroup = uibuttongroup(app.PostprocPanel);
            app.PlottypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottypeButtonGroupSelectionChanged, true);
            app.PlottypeButtonGroup.Title = 'Plot type  : ';
            app.AbsButton = uiradiobutton(app.PlottypeButtonGroup);
            app.AbsButton.Text = 'Abs';
            app.AbsButton.Value = true;
            app.AbsTriButton = uiradiobutton(app.PlottypeButtonGroup);
            app.AbsTriButton.Text = 'Abs Tri';
            app.QuiverButton = uiradiobutton(app.PlottypeButtonGroup);
            app.QuiverButton.Text = 'Quiver';
            app.ContourButton = uiradiobutton(app.PlottypeButtonGroup);
            app.ContourButton.Text = 'Contour';
            app.SliceButton = uiradiobutton(app.PlottypeButtonGroup);
            app.SliceButton.Text = 'Slice';
            app.FieldlinesButton = uiradiobutton(app.PlottypeButtonGroup);
            app.FieldlinesButton.Text = 'Field lines';
            app.OptionsPanel = uipanel(app.PostprocPanel);
            app.OptionsPanel.Title = 'Options : ';
            app.AxesOnCheckBox = uicheckbox(app.OptionsPanel);
            app.AxesOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @AxesOnCheckBoxValueChanged, true);
            app.AxesOnCheckBox.Text = 'Axes On';
            app.AxesOnCheckBox.Value = true;
            app.SavePlotCheckBox = uicheckbox(app.OptionsPanel);
            app.SavePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePlotCheckBoxValueChanged, true);
            app.SavePlotCheckBox.Text = 'Save Plot';
            app.FieldlinesOnCheckBox = uicheckbox(app.OptionsPanel);
            app.FieldlinesOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @FieldlinesOnCheckBoxValueChanged, true);
            app.FieldlinesOnCheckBox.Text = 'Field lines On';
            app.contoursEditFieldLabel = uilabel(app.OptionsPanel);
            app.contoursEditFieldLabel.Text = '# contours';
            app.contoursEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.contoursEditField.Limits = [0 100];
            app.contoursEditField.ValueChangedFcn = createCallbackFcn(app, @contoursEditFieldValueChanged, true);
            app.contoursEditField.Value = 24;
            app.ResolutionEditFieldLabel = uilabel(app.OptionsPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.ResolutionEditField.Limits = [50 400];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 200;
            app.FormatDropDownLabel = uilabel(app.OptionsPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.OptionsPanel);
            app.FormatDropDown.Items = {'PNG', 'JPG', 'EPS'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = 'PNG';
            app.DisplayButton = uibutton(app.PostprocPanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.CloseallfiguresButton = uibutton(app.PostprocPanel, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FEMLabApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMLabUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMLabUIFigure)
        end
    end
end